classdef mordleGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow            matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        NewGameButton           matlab.ui.control.Button
        KeyboardGoesHereButton  matlab.ui.control.Button
        GridAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        M mordle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.M = mordle(app.GridAxes);
        end

        % Key press function: FigureWindow
        function FigureWindowKeyPress(app, event)
            key = event.Key;

            if isscalar(key) && isletter(key)
                app.M.addLetter(key);
            elseif key == "backspace"
                app.M.deleteLetter;
            elseif key == "return"
                app.M.submitGuess;
            end
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.M.newGame;
            app.FigureWindow.focus;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Mordle';
            app.FigureWindow.KeyPressFcn = createCallbackFcn(app, @FigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.FigureWindow);
            app.GridAxes = uiaxes(app.GridLayout);
            title(app.GridAxes, 'Title')
            xlabel(app.GridAxes, 'X')
            ylabel(app.GridAxes, 'Y')
            zlabel(app.GridAxes, 'Z')
            app.KeyboardGoesHereButton = uibutton(app.GridLayout, 'push');
            app.KeyboardGoesHereButton.Text = 'Keyboard Goes Here';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NewGameButton = uibutton(app.GridLayout2, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mordleGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end